classdef Go < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        STARTButton               matlab.ui.control.Button
        SerialDropDownLabel       matlab.ui.control.Label
        SerialDropDown            matlab.ui.control.DropDown
        NegrasPanel               matlab.ui.container.Panel
        PasarButton               matlab.ui.control.Button
        RendirseButton            matlab.ui.control.Button
        JugarButton               matlab.ui.control.Button
        CapturasEditField_2Label  matlab.ui.control.Label
        CapturasEditField_2       matlab.ui.control.NumericEditField
        DropDown                  matlab.ui.control.DropDown
        BlancasPanel              matlab.ui.container.Panel
        EditField_2               matlab.ui.control.EditField
        CapturasEditFieldLabel    matlab.ui.control.Label
        CapturasEditField         matlab.ui.control.NumericEditField
        TextArea                  matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        M = zeros(5,5); % Matriz de posiciones
        histo = strings(0); % Historia de la partida
        turn = 1;
        passWhite = 0;
        passBlack = 0;
    end
    
    methods (Access = private)
        
        function board(app)
            hold(app.UIAxes_2,'off');
            for i=1:5
                plot(app.UIAxes_2,[1 5],[i i],'-k');
                hold(app.UIAxes_2,'on');
                plot(app.UIAxes_2,[i i],[1 5],'k');
            end
            plot(app.UIAxes_2,[0 0],[0 6],'-w');
            plot(app.UIAxes_2,[6 0],[6 6],'-w');
            plot(app.UIAxes_2,[6 6],[0 6],'-w');
            plot(app.UIAxes_2,[0 6],[0 0],'-w');
            for i=1:5
                for j=1:5
                    if app.M(i,j) == 1
                        plot(app.UIAxes_2,i,j,'.k','MarkerSize',50);
                    elseif app.M(i,j) == -1
                        plot(app.UIAxes_2,i,j,'.r','MarkerSize',50);
                    end
                end
            end
            
            axis(app.UIAxes_2,'equal');
            
            text(app.UIAxes_2,1,1,'A','HorizontalAlignment','center','VerticalAlignment','top');
            text(app.UIAxes_2,2,1,'B','HorizontalAlignment','center','VerticalAlignment','top');
            text(app.UIAxes_2,3,1,'C','HorizontalAlignment','center','VerticalAlignment','top');
            text(app.UIAxes_2,4,1,'D','HorizontalAlignment','center','VerticalAlignment','top');
            text(app.UIAxes_2,5,1,'E','HorizontalAlignment','center','VerticalAlignment','top');
            
            text(app.UIAxes_2,1,1,'1','HorizontalAlignment','right','VerticalAlignment','middle');
            text(app.UIAxes_2,1,2,'2','HorizontalAlignment','right','VerticalAlignment','middle');
            text(app.UIAxes_2,1,3,'3','HorizontalAlignment','right','VerticalAlignment','middle');
            text(app.UIAxes_2,1,4,'4','HorizontalAlignment','right','VerticalAlignment','middle');
            text(app.UIAxes_2,1,5,'5','HorizontalAlignment','right','VerticalAlignment','middle');
         
            set(app.UIAxes_2,'visible','off')    
        end
        
        function createInputFile(app,check,endgame)
            f = fopen("input.txt","w");
            fprintf(f,"boardsize 5\n");
            fprintf(f,"clear_board\n");
            
            for i=1:length(app.histo)
                fprintf(f,"play %s\n",app.histo(i));
            end
            if check == false
                fprintf(f,"genmove white\n");
                fprintf(f,"showboard\n");      
            end
            if endgame == true
                fprintf(f,"final_score\n");                   
            end
            fclose(f);
        end
        
        function showBoard(app)
            str = sscanf(fileread('output.txt'),"%s");
            
            expression = '([.+OX]{5})';
            matchStr = cell2mat(regexp(str,expression,'match'));
            
            Mstr = [matchStr(1:5); matchStr(6:10); matchStr(11:15); matchStr(16:20); matchStr(21:25);];
            
            app.M = zeros(5,5);
            for i=1:5
                for j=1:5
                    if Mstr(i,j) == 'X'
                        app.M(i,j) = 1;
                    elseif Mstr(i,j) == 'O'
                        app.M(i,j) = -1;
                    end
                end
            end

            app.M = rot90(app.M,3);
            
            
            expression = 'hascaptured(\d+)';
            matchStr = regexp(str,expression,'tokens');
            app.CapturasEditField_2.Value = str2double(cell2mat(matchStr{2}));
            app.CapturasEditField.Value   = str2double(cell2mat(matchStr{1}));
            end

        function [passed,resign] = playAI(app)
            passed = false; resign = false;
            
            delete output.txt;
            system("gnugo --mode gtp --gtp-input input.txt >> output.txt");
            str = sscanf(fileread('output.txt'),"%s")
            
            if str(length(app.histo) + 4) ~= 'r' && str(length(app.histo) + 4) ~= 'P'
                i = str(length(app.histo) + 4);
                j = str(length(app.histo) + 5);
                app.histo(end+1) = sprintf("white %c%c", i, j);
                app.EditField_2.Value = sprintf("%c%c",i,j);
            elseif str(length(app.histo) + 4) == 'r'
                resign = true;
            elseif str(length(app.histo) + 4) == 'P'
                passed = true;
            end
            
            
        end
    
        function ilegal = checkIlegal(app)
            ilegal = false; 
             
            delete output.txt;
            system("gnugo --mode gtp --gtp-input input.txt >> output.txt");
            str = sscanf(fileread('output.txt'),"%s");
            if  str(length(app.histo) + 2) == '?'
                ilegal = true;
            end

        end
    
        function compute(app)
             createInputFile(app,false,false);
                [passed,resign] = playAI(app);
                if passed
                       app.EditField_2.Value = "PASS";
                    if app.passBlack == 1
                         endGame(app) 
                    end
                elseif resign
                    app.RendirseButton_3.BackgroundColor = [1 0 0];
                    pause(1);
                    app.RendirseButton_3.BackgroundColor = [0.96 0.96 0.96];  
                    app.TextArea.Value = "BLANCAS SE RINDEN";
                else
                    app.passWhite = 0;
                end
                showBoard(app);
                board(app);          
        end
    
        function endGame(app)
            createInputFile(app,false,true);
            delete output.txt;
            system("gnugo --mode gtp --gtp-input input.txt >> output.txt");
            str = sscanf(fileread('output.txt'),"%s");
            expression ='([WB])([+\d\.]+)$';
            matchStr = regexp(str,expression,'tokens');
            if matchStr{1}{1} == 'W'
                app.TextArea.Value = sprintf("GANAN BLANCAS %s",matchStr{1}{2});
            else 
                app.TextArea.Value = sprintf("GANAN NEGRAS %s",matchStr{1}{2});  
            end
            
            pause(3);
        end
    end
        

    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
        app.M = zeros(5,5); % Matriz de posiciones
        app.histo = strings(0,0); % Historia de la partida
        app.turn = 1;
        board(app);
        app.TextArea.Value = "";

        end

        % Button pushed function: JugarButton
        function JugarButtonPushed(app, event)
            if app.turn == 1
                app.turn = 0;
                app.TextArea.Value = "";
                 
                app.histo(end+1) = sprintf("black %s", app.DropDown.Value);
                createInputFile(app,true,false);
                ilegal = checkIlegal(app);
                
                if ilegal
                    app.histo(end) = [];
                    app.turn = 1;
                    app.TextArea.Value = "ILEGAL";
                    return;
                end
                app.passBlack = 0;
                compute(app)
                app.turn = 1;
            end
        end

        % Button pushed function: PasarButton
        function PasarButtonPushed(app, event)
            if app.turn == 1
                app.turn = 0;
                app.passBlack = 1;
                if app.passWhite == 1
                    endGame(app); 
                end  
                compute(app);
                app.turn = 1;
            end
        end

        % Button pushed function: RendirseButton
        function RendirseButtonPushed(app, event)
                app.TextArea.Value = "NEGRAS SE RINDEN";            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.SerialDropDownLabel = uilabel(app.UIFigure);
            app.SerialDropDownLabel.Text = 'Serial';
            app.SerialDropDown = uidropdown(app.UIFigure);
            app.NegrasPanel = uipanel(app.UIFigure);
            app.NegrasPanel.Title = 'Negras';
            app.PasarButton = uibutton(app.NegrasPanel, 'push');
            app.PasarButton.ButtonPushedFcn = createCallbackFcn(app, @PasarButtonPushed, true);
            app.PasarButton.Text = 'Pasar';
            app.RendirseButton = uibutton(app.NegrasPanel, 'push');
            app.RendirseButton.ButtonPushedFcn = createCallbackFcn(app, @RendirseButtonPushed, true);
            app.RendirseButton.Text = 'Rendirse';
            app.JugarButton = uibutton(app.NegrasPanel, 'push');
            app.JugarButton.ButtonPushedFcn = createCallbackFcn(app, @JugarButtonPushed, true);
            app.JugarButton.Text = 'Jugar';
            app.CapturasEditField_2Label = uilabel(app.NegrasPanel);
            app.CapturasEditField_2Label.Text = 'Capturas';
            app.CapturasEditField_2 = uieditfield(app.NegrasPanel, 'numeric');
            app.CapturasEditField_2.ValueDisplayFormat = '%.0f';
            app.DropDown = uidropdown(app.NegrasPanel);
            app.DropDown.Items = {'A1', 'A2', 'A3', 'A4', 'A5', 'B1', 'B2', 'B3', 'B4', 'B5', 'C1', 'C2', 'C3', 'C4', 'C5', 'D1', 'D2', 'D3', 'D4', 'D5', 'E1', 'E2', 'E3', 'E4', 'E5'};
            app.DropDown.Value = 'A1';
            app.BlancasPanel = uipanel(app.UIFigure);
            app.BlancasPanel.Title = 'Blancas';
            app.EditField_2 = uieditfield(app.BlancasPanel, 'text');
            app.CapturasEditFieldLabel = uilabel(app.BlancasPanel);
            app.CapturasEditFieldLabel.Text = 'Capturas';
            app.CapturasEditField = uieditfield(app.BlancasPanel, 'numeric');
            app.CapturasEditField.ValueDisplayFormat = '%.0f';
            app.TextArea = uitextarea(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Go

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end