classdef Panel_Control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        PaneldecontrolTab              matlab.ui.container.Tab
        HomeButton                     matlab.ui.control.Button
        CadenaabiertaPanel             matlab.ui.container.Panel
        V2                             matlab.ui.control.Slider
        V3                             matlab.ui.control.Slider
        V1                             matlab.ui.control.Slider
        q1mmsLabel                     matlab.ui.control.Label
        q2gradsLabel                   matlab.ui.control.Label
        q3gradsLabel                   matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        SteppersSwitchLabel            matlab.ui.control.Label
        SteppersSwitch                 matlab.ui.control.Switch
        CadenacerradaPanel             matlab.ui.container.Panel
        q1Input                        matlab.ui.control.Spinner
        q2Input                        matlab.ui.control.Spinner
        q3Input                        matlab.ui.control.Spinner
        Enviar                         matlab.ui.control.Button
        LampC                          matlab.ui.control.Lamp
        RButton                        matlab.ui.control.Button
        q1mmLabel                      matlab.ui.control.Label
        q2degLabel                     matlab.ui.control.Label
        q3degLabel                     matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        ComunicacinPanel               matlab.ui.container.Panel
        PuertoDropDownLabel            matlab.ui.control.Label
        Puerto                         matlab.ui.control.DropDown
        BaudrateDropDownLabel          matlab.ui.control.Label
        Baudrate                       matlab.ui.control.DropDown
        ConectarButton                 matlab.ui.control.Button
        RButton_2                      matlab.ui.control.Button
        DesconectarButton              matlab.ui.control.Button
        PosicinPanel                   matlab.ui.container.Panel
        XEditFieldLabel                matlab.ui.control.Label
        X                              matlab.ui.control.NumericEditField
        YLabel_3                       matlab.ui.control.Label
        Y                              matlab.ui.control.NumericEditField
        ZLabel_3                       matlab.ui.control.Label
        Z                              matlab.ui.control.NumericEditField
        CadenaSwitch                   matlab.ui.control.ToggleSwitch
        VentosaPanel                   matlab.ui.container.Panel
        SliderVentosa                  matlab.ui.control.Slider
        GarraPanel                     matlab.ui.container.Panel
        SliderGarra                    matlab.ui.control.Slider
        AbrirButton                    matlab.ui.control.Button
        CerrarButton                   matlab.ui.control.Button
        CinemticaTab                   matlab.ui.container.Tab
        CinemticadirectaPanel          matlab.ui.container.Panel
        CoordenadasarticularesPanel    matlab.ui.container.Panel
        Q1Label                        matlab.ui.control.Label
        Q1d                            matlab.ui.control.Spinner
        Q2Label                        matlab.ui.control.Label
        Q2d                            matlab.ui.control.Spinner
        Q3Label                        matlab.ui.control.Label
        Q3d                            matlab.ui.control.Spinner
        CoordenadascartesianasPanel    matlab.ui.container.Panel
        ZLabel                         matlab.ui.control.Label
        Zd                             matlab.ui.control.NumericEditField
        YLabel                         matlab.ui.control.Label
        Yd                             matlab.ui.control.NumericEditField
        XLabel                         matlab.ui.control.Label
        Xd                             matlab.ui.control.NumericEditField
        Lamp_D                         matlab.ui.control.Lamp
        CalcularD                      matlab.ui.control.Button
        CinemticainversaPanel          matlab.ui.container.Panel
        CoordenadascartesianasPanel_2  matlab.ui.container.Panel
        YLabel_2                       matlab.ui.control.Label
        Yi_3                           matlab.ui.control.Spinner
        ZLabel_2                       matlab.ui.control.Label
        Zi_3                           matlab.ui.control.Spinner
        XLabel_2                       matlab.ui.control.Label
        Xi_3                           matlab.ui.control.Spinner
        Lamp_I                         matlab.ui.control.Lamp
        CoordenadasarticularesPanel_2  matlab.ui.container.Panel
        Q2Label_2                      matlab.ui.control.Label
        Q2i                            matlab.ui.control.NumericEditField
        Q3Label_2                      matlab.ui.control.Label
        Q3i                            matlab.ui.control.NumericEditField
        Q1Label_2                      matlab.ui.control.Label
        Q1i                            matlab.ui.control.NumericEditField
        CalcularI                      matlab.ui.control.Button
    end


    properties (Access = public)
       myTimer = 0; % Timer periodico
       arduino = 0; % Puerto serial
    end

    methods (Access = public)
    
        function main(app)
            q1=app.q1Input.Value;
            q2=app.q2Input.Value;
            q3=app.q3Input.Value;
            
            if app.CadenaSwitch.Value=="Abierta"
                app.V1.Enable = 'on';
                app.V2.Enable = 'on';
                app.V3.Enable = 'on';
                app.q1mmsLabel.Enable = 'on';
                app.q2gradsLabel.Enable = 'on';
                app.q3gradsLabel.Enable = 'on';
                
                
                app.q1Input.Enable = 'off';
                app.q2Input.Enable = 'off';
                app.q3Input.Enable = 'off';
                app.q1mmLabel.Enable = 'off';
                app.q2degLabel.Enable = 'off';
                app.q3degLabel.Enable = 'off';
            end
            
            if app.CadenaSwitch.Value=="Cerrada"
                
                
                % Detener movimiento
                mandarComando(app, sprintf('J2 M%d %f\n', 1, 0));
                mandarComando(app, sprintf('J2 M%d %f\n', 2, 0));
                mandarComando(app, sprintf('J2 M%d %f\n', 3, 0));
                
                app.V1.Enable = 'off';
                app.V2.Enable = 'off';
                app.V3.Enable = 'off';
                app.q1mmsLabel.Enable = 'off';
                app.q2gradsLabel.Enable = 'off';
                app.q3gradsLabel.Enable = 'off';
                app.q1Input.Enable = 'on';
                app.q2Input.Enable = 'on';
                app.q3Input.Enable = 'on';
                app.q1mmLabel.Enable = 'on';
                app.q2degLabel.Enable = 'on';
                app.q3degLabel.Enable = 'on';
            end
            
        end
        
        function consultarAngulos(app,~) % Callback para el timer
           q1 = consultarPosicion(app,1); 
           q2 = consultarPosicion(app,2);
           q3 = consultarPosicion(app,3);

           app.Q1d.Value = q1;
           app.Q2d.Value = deg2rad(q2);
           app.Q3d.Value = deg2rad(q3);
           

            [x2,y2,z2,x1,y1,z1] = directa(app.Q1d.Value,app.Q2d.Value,app.Q3d.Value);
            app.X.Value = x2;
            app.Y.Value = y2;
            app.Z.Value = z2;


            % PLOT
            cla(app.UIAxes);
            hold(app.UIAxes,'on');
                       
            plot3(app.UIAxes,[x1 x1 x2] ,[0 y1 y2] ,[0 z1 z2],'k');
            set(findall(gca, 'Type', 'Line'),'LineWidth',3);
            
            quiver3(app.UIAxes,0,0,0,50,0,0,'r');
            quiver3(app.UIAxes,0,0,0,0,50,0,'g');
            quiver3(app.UIAxes,0,0,0,0,0,50,'b');

            quiver3(app.UIAxes,x1,0,0,50,0,0,'r');
            quiver3(app.UIAxes,x1,0,0,0,50,0,'g');
            quiver3(app.UIAxes,x1,0,0,0,0,50,'b');
            
            G = [1 0 0; 0 cosd(q2) -sind(q2); 0 sind(q2) cosd(q2)];
            o1 = G*eye(3)*50;
            quiver3(app.UIAxes,x1,y1,z1,o1(1,1),o1(2,1),o1(3,1),'r');
            quiver3(app.UIAxes,x1,y1,z1,o1(1,2),o1(2,2),o1(3,2),'g');
            quiver3(app.UIAxes,x1,y1,z1,o1(1,3),o1(2,3),o1(3,3),'b'); 
            
            G = [1 0 0; 0 cosd(q3) -sind(q3); 0 sind(q3) cosd(q3)];
            o2 = G*eye(3)*50;            
            quiver3(app.UIAxes,x2,y2,z2,o2(1,1),o2(2,1),o2(3,1),'r');
            quiver3(app.UIAxes,x2,y2,z2,o2(1,2),o2(2,2),o2(3,2),'g');
            quiver3(app.UIAxes,x2,y2,z2,o2(1,3),o2(2,3),o2(3,3),'b'); 

            hold(app.UIAxes,'off');
            % PLOT


           %[app.X.Value,app.Y.Value,app.Z.Value] = directa(q1,q2,q3); %Actualiza la posicion
%            if (constrains([app.X.Value,app.Y.Value,app.Z.Value])==1)&&(app.CadenaSwitch.Value=="Abierta")
%                app.Lamp.Color='Green';
%            end
%            if (constrains([app.X.Value,app.Y.Value,app.Z.Value])==0)&&(app.CadenaSwitch.Value=="Abierta")
%                mandarComando(app, sprintf('J2 M%d %f\n', 1, 0.0));
%                mandarComando(app, sprintf('J2 M%d %f\n', 2, 0.0));
%                mandarComando(app, sprintf('J2 M%d %f\n', 3, 0.0));
%                app.Lamp.Color='Red';
%                app.V1.Value=0;
%                app.V2.Value=0;
%                app.V3.Value=0;
%                
%            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         app.Puerto.Items=seriallist;
         app.Baudrate.Items = {'300', '600', '1200', '2400', '4800', '9600', '14400', '19200', '28800', '38400', '57600', '115200'};
         app.Baudrate.Value = {'9600'};
         main(app);
        end

        % Button pushed function: CalcularD
        function CalcularDButtonPushed(app, event)
            Coord=[0 0 0];
            [Coord(1), Coord(2), Coord(3)]=directa(app.Q1d.Value,app.Q2d.Value,app.Q3d.Value);
            if constrains(Coord)==1
               app.Lamp_D.Color='Green';
               app.Xd.Value=Coord(1);
               app.Yd.Value=Coord(2);
               app.Zd.Value=Coord(3);
            else  
               app.Lamp_D.Color='Red';
            end
        end

        % Callback function
        function BotonGraficoButtonPushed(app, event)

        end

        % Button pushed function: CalcularI
        function CalcularIPushed(app, event)
            x=[app.Xi.Value app.Yi.Value app.Zi.Value];
            if constrains(x)==1
               app.Lamp.Color='Green';
               [app.Q1i.Value,app.Q2i.Value,app.Q3i.Value] = inversa(app.Xi.Value, app.Yi.Value, app.Zi.Value);
            else  
               app.Lamp.Color='Red';
            end
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            mandarComando(app,'J0');
            set(app.HomeButton,'BackgroundColor',[0.6 1 0.6]);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Detener timer
            try
            stop(app.myTimer);
            delete(app.myTimer);
            catch
            end
            % Detener conexion serie
            try
            fclose(app.arduino);
            delete(app.UIFigure);
            catch
                set(app.ConectarButton,'BackgroundColor','red');
            end
            
        end

        % Value changed function: Baudrate, Puerto
        function PuertoValueChanged(app, event)
            app.arduino=serial(app.Puerto.Value,'BaudRate', str2double(app.Baudrate.Value));
            try
                fopen(app.arduino);
                set(app.ConectarButton,'BackgroundColor',[0.6 1 0.6]);
            catch
                set(app.ConectarButton,'BackgroundColor','red');
            end
        end

        % Value changed function: SteppersSwitch
        function SteppersSwitchValueChanged(app, event)
            mandarComando(app,'J3');
            
        end

        % Value changed function: V2
        function V2ValueChanged(app, event)
           mandarComando(app, sprintf('J2 M%d %f\n', 2, app.V2.Value));
            
        end

        % Value changed function: V3
        function V3ValueChanged(app, event)
            mandarComando(app, sprintf('J2 M%d %f\n', 3,app.V3.Value));
            
        end

        % Value changed function: V1
        function V1ValueChanged(app, event)
            mandarComando(app, sprintf('J2 M%d %f\n', 1, app.V1.Value));
            
        end

        % Value changed function: CadenaSwitch
        function CadenaSwitchValueChanged(app, event)
            main(app);
        end

        % Button pushed function: Enviar
        function EnviarButtonPushed(app, event)
            if app.CadenaSwitch.Value=="Abierta"
                q1=app.V1.Value;
                q2=app.V2.Value;
                q3=app.V3.Value;
                mandarComando(app, sprintf('J2 M%d %f\n', 1, q1));
                mandarComando(app, sprintf('J2 M%d %f\n', 2, q2));
                mandarComando(app, sprintf('J2 M%d %f\n', 3, q3));
            end
            
            if app.CadenaSwitch.Value=="Cerrada"
                q1=app.q1Input.Value;
                q2=app.q2Input.Value;
                q3=app.q3Input.Value;
                [x1,x2,x3]=directa(q1,deg2rad(q2),deg2rad(q3));
                
                if constrains([x1,x2,x3])==1
                   mandarComando(app, sprintf('J1 M%d %f\n', 1, q1));
                   mandarComando(app, sprintf('J1 M%d %f\n', 2, q2));
                   mandarComando(app, sprintf('J1 M%d %f\n', 3, q3));
                   app.LampC.Color='Green';
                
                else
                app.LampC.Color='Red';
                end
            end
          %  pintar_app(app,q1,q2,q3)
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            delete(instrfindall);
            app.arduino=serial(app.Puerto.Value,'BaudRate',str2double(app.Baudrate.Value));
            try
                fopen(app.arduino);
                set(app.ConectarButton,'BackgroundColor',[0.6 1 0.6]);
                set(app.DesconectarButton,'BackgroundColor',[0.96,0.96,0.96]);
                pause(2);
                
            catch
                set(app.ConectarButton,'BackgroundColor','red');
               
            end
            
            try
                app.myTimer = timer('TimerFcn', @(~,~) app.consultarAngulos, ... 
                'ExecutionMode', 'fixedRate');
                app.myTimer.Period = 1;
                start(app.myTimer)
            catch
            end
        end

        % Button pushed function: RButton
        function RButtonPushed(app, event)
            app.q1Input.Value=0;
            app.q2Input.Value=0;
            app.q3Input.Value=0;
            app.V1.Value=0;
            app.V2.Value=0;
            app.V3.Value=0;
            mandarComando(app, sprintf('J2 M%d %f\n', 2, app.V2.Value))
            mandarComando(app, sprintf('J2 M%d %f\n', 1, app.V1.Value));
            mandarComando(app, sprintf('J2 M%d %f\n', 3, app.V3.Value));
            
            app.Lamp.Color=[0.8,0.8,0.8];
            app.LampC.Color=[0.8,0.8,0.8];
            app.SliderVentosa.Value=0;
            app.SliderGarra.Value=0;
        end

        % Button pushed function: RButton_2
        function RButton_2Pushed(app, event)
            app.Puerto.Items=seriallist;
        end

        % Button pushed function: DesconectarButton
        function DesconectarButtonPushed(app, event)
            try
                fclose(app.arduino);
                set(app.DesconectarButton,'BackgroundColor',[0.6 1 0.6]);
                set(app.ConectarButton,'BackgroundColor',[0.96,0.96,0.96]);
                set(app.HomeButton,'BackgroundColor','red');

            catch
                set(app.DesconectarButton,'BackgroundColor','red');
            end
        end

        % Value changed function: SliderVentosa
        function SliderVentosaValueChanged(app, event)
            mandarComando(app, sprintf('J4 M%d %f\n', 1, app.SliderVentosa.Value));
            
        end

        % Button pushed function: AbrirButton
        function AbrirButtonPushed(app, event)
           mandarComando(app, sprintf('J4 M%d %f\n', 2, 500)); 
        end

        % Button pushed function: CerrarButton
        function CerrarButtonPushed(app, event)
           mandarComando(app, sprintf('J4 M%d %f\n', 2, -500)); 
        end

        % Value changed function: SliderGarra
        function SliderGarraValueChanged(app, event)
            mandarComando(app, sprintf('J4 M%d %f\n', 2, app.SliderGarra.Value));
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PaneldecontrolTab = uitab(app.TabGroup);
            app.PaneldecontrolTab.Title = 'Panel de control';
            app.HomeButton = uibutton(app.PaneldecontrolTab, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.CadenaabiertaPanel = uipanel(app.PaneldecontrolTab);
            app.CadenaabiertaPanel.Title = 'Cadena abierta';
            app.V2 = uislider(app.CadenaabiertaPanel);
            app.V2.Limits = [-10 10];
            app.V2.ValueChangedFcn = createCallbackFcn(app, @V2ValueChanged, true);
            app.V2.Enable = 'off';
            app.V3 = uislider(app.CadenaabiertaPanel);
            app.V3.Limits = [-10 10];
            app.V3.ValueChangedFcn = createCallbackFcn(app, @V3ValueChanged, true);
            app.V3.Enable = 'off';
            app.V1 = uislider(app.CadenaabiertaPanel);
            app.V1.Limits = [-150 150];
            app.V1.ValueChangedFcn = createCallbackFcn(app, @V1ValueChanged, true);
            app.V1.Enable = 'off';
            app.q1mmsLabel = uilabel(app.CadenaabiertaPanel);
            app.q1mmsLabel.Enable = 'off';
            app.q1mmsLabel.Text = 'q1 (mm/s)';
            app.q2gradsLabel = uilabel(app.CadenaabiertaPanel);
            app.q2gradsLabel.Enable = 'off';
            app.q2gradsLabel.Text = 'q2 (grad/s)';
            app.q3gradsLabel = uilabel(app.CadenaabiertaPanel);
            app.q3gradsLabel.Enable = 'off';
            app.q3gradsLabel.Text = 'q3 (grad/s)';
            app.Lamp = uilamp(app.CadenaabiertaPanel);
            app.SteppersSwitchLabel = uilabel(app.PaneldecontrolTab);
            app.SteppersSwitchLabel.Text = 'Steppers';
            app.SteppersSwitch = uiswitch(app.PaneldecontrolTab, 'slider');
            app.SteppersSwitch.ValueChangedFcn = createCallbackFcn(app, @SteppersSwitchValueChanged, true);
            app.SteppersSwitch.Value = 'On';
            app.CadenacerradaPanel = uipanel(app.PaneldecontrolTab);
            app.CadenacerradaPanel.Title = 'Cadena cerrada';
            app.q1Input = uispinner(app.CadenacerradaPanel);
            app.q1Input.Enable = 'off';
            app.q2Input = uispinner(app.CadenacerradaPanel);
            app.q2Input.Enable = 'off';
            app.q3Input = uispinner(app.CadenacerradaPanel);
            app.q3Input.Enable = 'off';
            app.Enviar = uibutton(app.CadenacerradaPanel, 'push');
            app.Enviar.ButtonPushedFcn = createCallbackFcn(app, @EnviarButtonPushed, true);
            app.Enviar.Text = 'Enviar';
            app.LampC = uilamp(app.CadenacerradaPanel);
            app.RButton = uibutton(app.CadenacerradaPanel, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.RButton.Text = 'R';
            app.q1mmLabel = uilabel(app.CadenacerradaPanel);
            app.q1mmLabel.Enable = 'off';
            app.q1mmLabel.Text = 'q1 (mm)';
            app.q2degLabel = uilabel(app.CadenacerradaPanel);
            app.q2degLabel.Enable = 'off';
            app.q2degLabel.Text = 'q2 (deg)';
            app.q3degLabel = uilabel(app.CadenacerradaPanel);
            app.q3degLabel.Enable = 'off';
            app.q3degLabel.Text = 'q3 (deg)';
            app.UIAxes = uiaxes(app.PaneldecontrolTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-100 300];
            app.UIAxes.YLim = [-100 500];
            app.UIAxes.ZLim = [-100 400];
            app.ComunicacinPanel = uipanel(app.PaneldecontrolTab);
            app.ComunicacinPanel.Title = 'Comunicación';
            app.PuertoDropDownLabel = uilabel(app.ComunicacinPanel);
            app.PuertoDropDownLabel.Text = 'Puerto';
            app.Puerto = uidropdown(app.ComunicacinPanel);
            app.Puerto.Items = {};
            app.Puerto.ValueChangedFcn = createCallbackFcn(app, @PuertoValueChanged, true);
            app.Puerto.Value = {};
            app.BaudrateDropDownLabel = uilabel(app.ComunicacinPanel);
            app.BaudrateDropDownLabel.Text = 'Baudrate';
            app.Baudrate = uidropdown(app.ComunicacinPanel);
            app.Baudrate.Items = {};
            app.Baudrate.ValueChangedFcn = createCallbackFcn(app, @PuertoValueChanged, true);
            app.Baudrate.Value = {};
            app.ConectarButton = uibutton(app.ComunicacinPanel, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.RButton_2 = uibutton(app.ComunicacinPanel, 'push');
            app.RButton_2.ButtonPushedFcn = createCallbackFcn(app, @RButton_2Pushed, true);
            app.RButton_2.Text = 'R';
            app.DesconectarButton = uibutton(app.ComunicacinPanel, 'push');
            app.DesconectarButton.ButtonPushedFcn = createCallbackFcn(app, @DesconectarButtonPushed, true);
            app.DesconectarButton.Text = 'Desconectar';
            app.PosicinPanel = uipanel(app.PaneldecontrolTab);
            app.PosicinPanel.Title = 'Posición';
            app.XEditFieldLabel = uilabel(app.PosicinPanel);
            app.XEditFieldLabel.Text = 'X';
            app.X = uieditfield(app.PosicinPanel, 'numeric');
            app.X.Editable = 'off';
            app.YLabel_3 = uilabel(app.PosicinPanel);
            app.YLabel_3.Text = 'Y';
            app.Y = uieditfield(app.PosicinPanel, 'numeric');
            app.Y.Editable = 'off';
            app.ZLabel_3 = uilabel(app.PosicinPanel);
            app.ZLabel_3.Text = 'Z';
            app.Z = uieditfield(app.PosicinPanel, 'numeric');
            app.Z.Editable = 'off';
            app.CadenaSwitch = uiswitch(app.PaneldecontrolTab, 'toggle');
            app.CadenaSwitch.Items = {'Cerrada', 'Abierta'};
            app.CadenaSwitch.ValueChangedFcn = createCallbackFcn(app, @CadenaSwitchValueChanged, true);
            app.CadenaSwitch.Value = 'Abierta';
            app.VentosaPanel = uipanel(app.PaneldecontrolTab);
            app.VentosaPanel.Title = 'Ventosa';
            app.SliderVentosa = uislider(app.VentosaPanel);
            app.SliderVentosa.Limits = [0 255];
            app.SliderVentosa.ValueChangedFcn = createCallbackFcn(app, @SliderVentosaValueChanged, true);
            app.GarraPanel = uipanel(app.PaneldecontrolTab);
            app.GarraPanel.Title = 'Garra';
            app.SliderGarra = uislider(app.GarraPanel);
            app.SliderGarra.Limits = [0 255];
            app.SliderGarra.ValueChangedFcn = createCallbackFcn(app, @SliderGarraValueChanged, true);
            app.AbrirButton = uibutton(app.GarraPanel, 'push');
            app.AbrirButton.ButtonPushedFcn = createCallbackFcn(app, @AbrirButtonPushed, true);
            app.AbrirButton.Text = 'Abrir';
            app.CerrarButton = uibutton(app.GarraPanel, 'push');
            app.CerrarButton.ButtonPushedFcn = createCallbackFcn(app, @CerrarButtonPushed, true);
            app.CerrarButton.Text = 'Cerrar';
            app.CinemticaTab = uitab(app.TabGroup);
            app.CinemticaTab.Title = 'Cinemática';
            app.CinemticadirectaPanel = uipanel(app.CinemticaTab);
            app.CinemticadirectaPanel.Title = 'Cinemática directa';
            app.CoordenadasarticularesPanel = uipanel(app.CinemticadirectaPanel);
            app.CoordenadasarticularesPanel.Title = 'Coordenadas articulares';
            app.Q1Label = uilabel(app.CoordenadasarticularesPanel);
            app.Q1Label.Text = 'Q1';
            app.Q1d = uispinner(app.CoordenadasarticularesPanel);
            app.Q2Label = uilabel(app.CoordenadasarticularesPanel);
            app.Q2Label.Text = 'Q2';
            app.Q2d = uispinner(app.CoordenadasarticularesPanel);
            app.Q2d.Value = 1.5;
            app.Q3Label = uilabel(app.CoordenadasarticularesPanel);
            app.Q3Label.Text = 'Q3';
            app.Q3d = uispinner(app.CoordenadasarticularesPanel);
            app.Q3d.Value = -0.29;
            app.CoordenadascartesianasPanel = uipanel(app.CinemticadirectaPanel);
            app.CoordenadascartesianasPanel.Title = 'Coordenadas cartesianas';
            app.ZLabel = uilabel(app.CoordenadascartesianasPanel);
            app.ZLabel.Text = 'Z';
            app.Zd = uieditfield(app.CoordenadascartesianasPanel, 'numeric');
            app.Zd.Editable = 'off';
            app.YLabel = uilabel(app.CoordenadascartesianasPanel);
            app.YLabel.Text = 'Y';
            app.Yd = uieditfield(app.CoordenadascartesianasPanel, 'numeric');
            app.Yd.Editable = 'off';
            app.XLabel = uilabel(app.CoordenadascartesianasPanel);
            app.XLabel.Text = 'X';
            app.Xd = uieditfield(app.CoordenadascartesianasPanel, 'numeric');
            app.Xd.Editable = 'off';
            app.Lamp_D = uilamp(app.CinemticadirectaPanel);
            app.CalcularD = uibutton(app.CinemticadirectaPanel, 'push');
            app.CalcularD.ButtonPushedFcn = createCallbackFcn(app, @CalcularDButtonPushed, true);
            app.CalcularD.Text = 'Calcular';
            app.CinemticainversaPanel = uipanel(app.CinemticaTab);
            app.CinemticainversaPanel.Title = 'Cinemática inversa';
            app.CoordenadascartesianasPanel_2 = uipanel(app.CinemticainversaPanel);
            app.CoordenadascartesianasPanel_2.Title = 'Coordenadas cartesianas';
            app.YLabel_2 = uilabel(app.CoordenadascartesianasPanel_2);
            app.YLabel_2.Text = 'Y';
            app.Yi_3 = uispinner(app.CoordenadascartesianasPanel_2);
            app.ZLabel_2 = uilabel(app.CoordenadascartesianasPanel_2);
            app.ZLabel_2.Text = 'Z';
            app.Zi_3 = uispinner(app.CoordenadascartesianasPanel_2);
            app.XLabel_2 = uilabel(app.CoordenadascartesianasPanel_2);
            app.XLabel_2.Text = 'X';
            app.Xi_3 = uispinner(app.CoordenadascartesianasPanel_2);
            app.Lamp_I = uilamp(app.CoordenadascartesianasPanel_2);
            app.CoordenadasarticularesPanel_2 = uipanel(app.CinemticainversaPanel);
            app.CoordenadasarticularesPanel_2.Title = 'Coordenadas articulares';
            app.Q2Label_2 = uilabel(app.CoordenadasarticularesPanel_2);
            app.Q2Label_2.Text = 'Q2';
            app.Q2i = uieditfield(app.CoordenadasarticularesPanel_2, 'numeric');
            app.Q2i.Editable = 'off';
            app.Q3Label_2 = uilabel(app.CoordenadasarticularesPanel_2);
            app.Q3Label_2.Text = 'Q3';
            app.Q3i = uieditfield(app.CoordenadasarticularesPanel_2, 'numeric');
            app.Q3i.Editable = 'off';
            app.Q1Label_2 = uilabel(app.CoordenadasarticularesPanel_2);
            app.Q1Label_2.Text = 'Q1';
            app.Q1i = uieditfield(app.CoordenadasarticularesPanel_2, 'numeric');
            app.Q1i.Editable = 'off';
            app.CalcularI = uibutton(app.CinemticainversaPanel, 'push');
            app.CalcularI.ButtonPushedFcn = createCallbackFcn(app, @CalcularIPushed, true);
            app.CalcularI.Text = 'Calcular';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Panel_Control

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end